classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UltimusgluedispenserPanel    matlab.ui.container.Panel
        PressurebarEditFieldLabel    matlab.ui.control.Label
        PressurebarEditField         matlab.ui.control.NumericEditField
        VacuumH2OLabel               matlab.ui.control.Label
        VacuumH2OEditField           matlab.ui.control.NumericEditField
        TimemsEditFieldLabel         matlab.ui.control.Label
        TimemsEditField              matlab.ui.control.NumericEditField
        SetButton                    matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        CustomCommandsTextAreaLabel  matlab.ui.control.Label
        CustomCommandsTextArea       matlab.ui.control.TextArea
        DispensingModeButtonGroup    matlab.ui.container.ButtonGroup
        TimingButton                 matlab.ui.control.RadioButton
        ContinuousButton             matlab.ui.control.RadioButton
        SetButton_2                  matlab.ui.control.Button
        SetButton_3                  matlab.ui.control.Button
        StartDispensingButton        matlab.ui.control.Button
        SetDefaultsButton            matlab.ui.control.Button
        LoadsettingsButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dispenser)
           app.CallingApp = mainapp; 
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
        value = app.DispensingModeButtonGroup.SelectedObject.Text
        disp(value);
            switch value
                case 'Timing'
                    app.TimemsEditField.Enable = 1;
                    disp("Timing mode is active");
                case 'Continuous'
                    app.TimemsEditField.Enable = 0;
                    disp("Continuous mode is active");
                otherwise
                    fprintf ("\nError de merluzo, el Dispensing Mode Button no rula!!!\n")
            end
            app.StartDispensingButton.BackgroundColor = [1 1 1];
            app.SetButton.BackgroundColor = [1 1 1];
            app.SetButton_2.BackgroundColor = [1 1 1];
            app.SetButton_3.BackgroundColor = [1 1 1];
            drawnow limitrate;
        end

        % Callback function: PressurebarEditField, SetButton
        function PressurebarEditFieldValueChanged(app, event)
            app.SetButton.BackgroundColor = [1 1 1];
            pause(0.5);
            value = app.PressurebarEditField.Value;
            error = 0;
            cmd = sprintf ('E6--01');      % Set dispenser units in BAR
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            value = value * 1000;
            cmd = sprintf('PS--%04d',value);    % Set dispenser pressure to 40 KPA = 0.4 BAR
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            if error == 0
                app.SetButton.BackgroundColor = 'g';
            else
                app.SetButton.BackgroundColor = 'r';
            end
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the button in main app
            app.CallingApp.SettingsButton.Enable = 'on';
            delete(app)
        end

        % Callback function: SetButton_2, VacuumH2OEditField
        function VacuumH2OEditFieldValueChanged(app, event)
            app.SetButton_2.BackgroundColor = [1 1 1];
            pause(0.5);
            value = app.VacuumH2OEditField.Value;
            error = 0;
            cmd = sprintf('E7--01');      % Set dispenser Vacuum unit "H2O
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            value = value * 10;
            cmd = sprintf('VS--%04d', value);    % Set dispenser vacuum to 0.12 KPA = 0.5 "H2O
            disp (cmd);
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            if error == 0
                app.SetButton_2.BackgroundColor = 'g';
            else
                app.SetButton_2.BackgroundColor = 'r';
                disp("Error");
            end
        end

        % Callback function: SetButton_3, TimemsEditField
        function TimemsEditFieldValueChanged(app, event)
            app.SetButton_3.BackgroundColor = [1 1 1];
            value = app.TimemsEditField.Value;
            error = 0;
            cmd = sprintf('TT--');           %Setting timing mode
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            
            cmd = sprintf('DS-T%04d',value); %Setting time in ms
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            pause(0.1);
            if error == 0
                app.SetButton_3.BackgroundColor = 'g';
            else
                app.SetButton_3.BackgroundColor = 'r';
                disp("Error");
            end
        end

        % Button pushed function: StartDispensingButton
        function StartDispensingButtonPushed(app, event)
        app.StartDispensingButton.BackgroundColor = [1 1 1];
        pause(0.5);
            cmd = sprintf('DI--');
            error = app.CallingApp.dispenser.SetUltimus(cmd);
            if error == 0
                app.StartDispensingButton.BackgroundColor = 'g';
            else
                app.StartDispensingButton.BackgroundColor = 'r';
                disp("Error");
            end
        end

        % Button pushed function: SetDefaultsButton
        function SetDefaultsButtonPushed(app, event)
            app.PressurebarEditField.Value = 4.0;
            app.PressurebarEditFieldValueChanged();
            app.VacuumH2OEditField.Value = 0.5;
            app.VacuumH2OEditFieldValueChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UltimusgluedispenserPanel = uipanel(app.UIFigure);
            app.UltimusgluedispenserPanel.Title = 'Ultimus glue dispenser';
            app.PressurebarEditFieldLabel = uilabel(app.UltimusgluedispenserPanel);
            app.PressurebarEditFieldLabel.Text = 'Pressure (bar)';
            app.PressurebarEditField = uieditfield(app.UltimusgluedispenserPanel, 'numeric');
            app.PressurebarEditField.Limits = [0 8];
            app.PressurebarEditField.ValueDisplayFormat = '%5.2f';
            app.PressurebarEditField.ValueChangedFcn = createCallbackFcn(app, @PressurebarEditFieldValueChanged, true);
            app.PressurebarEditField.Value = 4;
            app.VacuumH2OLabel = uilabel(app.UltimusgluedispenserPanel);
            app.VacuumH2OLabel.Text = 'Vacuum ("H2O)';
            app.VacuumH2OEditField = uieditfield(app.UltimusgluedispenserPanel, 'numeric');
            app.VacuumH2OEditField.Limits = [0.1 18];
            app.VacuumH2OEditField.ValueDisplayFormat = '%5.1f';
            app.VacuumH2OEditField.ValueChangedFcn = createCallbackFcn(app, @VacuumH2OEditFieldValueChanged, true);
            app.VacuumH2OEditField.Value = 0.5;
            app.TimemsEditFieldLabel = uilabel(app.UltimusgluedispenserPanel);
            app.TimemsEditFieldLabel.Text = 'Time (ms)';
            app.TimemsEditField = uieditfield(app.UltimusgluedispenserPanel, 'numeric');
            app.TimemsEditField.Limits = [0 10000];
            app.TimemsEditField.ValueDisplayFormat = '%5d';
            app.TimemsEditField.ValueChangedFcn = createCallbackFcn(app, @TimemsEditFieldValueChanged, true);
            app.SetButton = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @PressurebarEditFieldValueChanged, true);
            app.SetButton.Text = 'Set';
            app.CloseButton = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.CustomCommandsTextAreaLabel = uilabel(app.UltimusgluedispenserPanel);
            app.CustomCommandsTextAreaLabel.Text = 'Custom Commands';
            app.CustomCommandsTextArea = uitextarea(app.UltimusgluedispenserPanel);
            app.DispensingModeButtonGroup = uibuttongroup(app.UltimusgluedispenserPanel);
            app.DispensingModeButtonGroup.Title = 'Dispensing Mode';
            app.TimingButton = uiradiobutton(app.DispensingModeButtonGroup);
            app.TimingButton.Text = 'Timing';
            app.TimingButton.Value = true;
            app.ContinuousButton = uiradiobutton(app.DispensingModeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.SetButton_2 = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @VacuumH2OEditFieldValueChanged, true);
            app.SetButton_2.Text = 'Set';
            app.SetButton_3 = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @TimemsEditFieldValueChanged, true);
            app.SetButton_3.Text = 'Set';
            app.StartDispensingButton = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.StartDispensingButton.ButtonPushedFcn = createCallbackFcn(app, @StartDispensingButtonPushed, true);
            app.StartDispensingButton.Text = 'Start Dispensing';
            app.SetDefaultsButton = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.SetDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultsButtonPushed, true);
            app.SetDefaultsButton.Text = 'Set Defaults';
            app.LoadsettingsButton = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.LoadsettingsButton.Text = 'Load settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end