classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ActionsButtonGroup        matlab.ui.container.ButtonGroup
        TouchDownButton           matlab.ui.control.ToggleButton
        ManualButton              matlab.ui.control.ToggleButton
        zzButton                  matlab.ui.control.ToggleButton
        HomeButton                matlab.ui.control.ToggleButton
        GluedispensingButton      matlab.ui.control.ToggleButton
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        Z1EditFieldLabel          matlab.ui.control.Label
        Z1EditField               matlab.ui.control.NumericEditField
        Z2EditFieldLabel          matlab.ui.control.Label
        Z2EditField               matlab.ui.control.NumericEditField
        EditFieldLabel            matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        ConnectGantryCheckBox     matlab.ui.control.CheckBox
        ConnectDispenserCheckBox  matlab.ui.control.CheckBox
        ConnectCameraCheckBox     matlab.ui.control.CheckBox
        XEditField_2Label         matlab.ui.control.Label
        XEditField_2              matlab.ui.control.NumericEditField
        YEditField_2Label         matlab.ui.control.Label
        YEditField_2              matlab.ui.control.NumericEditField
        Z1EditField_2Label        matlab.ui.control.Label
        Z1EditField_2             matlab.ui.control.NumericEditField
        Z2EditField_2Label        matlab.ui.control.Label
        Z2EditField_2             matlab.ui.control.NumericEditField
        EditField_2Label          matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        CurrentPositionLabel      matlab.ui.control.Label
        GantryMotorsPanel         matlab.ui.container.Panel
        UIAxes2                   matlab.ui.control.UIAxes
        Lamp_xMotor               matlab.ui.control.Lamp
        Lamp_yMotor               matlab.ui.control.Lamp
        Lamp_z1Motor              matlab.ui.control.Lamp
        Lamp_z2Motor              matlab.ui.control.Lamp
        Lamp_uMotor               matlab.ui.control.Lamp
        XSwitchLabel              matlab.ui.control.Label
        XSwitch                   matlab.ui.control.Switch
        YSwitchLabel              matlab.ui.control.Label
        YSwitch                   matlab.ui.control.Switch
        Z1SwitchLabel             matlab.ui.control.Label
        Z1Switch                  matlab.ui.control.Switch
        Z2SwitchLabel             matlab.ui.control.Label
        Z2Switch                  matlab.ui.control.Switch
        AllSwitchLabel            matlab.ui.control.Label
        AllSwitch                 matlab.ui.control.ToggleSwitch
        USwitchLabel              matlab.ui.control.Label
        USwitch                   matlab.ui.control.Switch
        StopButton                matlab.ui.control.StateButton
        ConnectButton_2           matlab.ui.control.Button
        DisconnectButton_2        matlab.ui.control.Button
        XEditField_3Label         matlab.ui.control.Label
        XEditField_3              matlab.ui.control.NumericEditField
        YEditField_3Label         matlab.ui.control.Label
        YEditField_3              matlab.ui.control.NumericEditField
        Z1EditField_3Label        matlab.ui.control.Label
        Z1EditField_3             matlab.ui.control.NumericEditField
        Z2EditField_3Label        matlab.ui.control.Label
        Z2EditField_3             matlab.ui.control.NumericEditField
        EditField_3Label          matlab.ui.control.Label
        EditField_3               matlab.ui.control.NumericEditField
        CameraViewPanel           matlab.ui.container.Panel
        SettingsButton            matlab.ui.control.Button
        CloseButton               matlab.ui.control.Button
        MovementButtonGroup       matlab.ui.container.ButtonGroup
        JoystickButton            matlab.ui.control.RadioButton
        DefaultButton             matlab.ui.control.RadioButton
        LinearButton              matlab.ui.control.RadioButton
        AutoButton                matlab.ui.control.RadioButton
        EditFieldLabel_2          matlab.ui.control.Label
        EditField_4               matlab.ui.control.NumericEditField
        SpeedLabel                matlab.ui.control.Label
        XEditField_4Label         matlab.ui.control.Label
        XEditField_4              matlab.ui.control.NumericEditField
        YEditField_4Label         matlab.ui.control.Label
        YEditField_4              matlab.ui.control.NumericEditField
        Z1EditField_4Label        matlab.ui.control.Label
        Z1EditField_4             matlab.ui.control.NumericEditField
        Z2EditField_4Label        matlab.ui.control.Label
        Z2EditField_4             matlab.ui.control.NumericEditField
        SelectListBoxLabel        matlab.ui.control.Label
        SelectListBox             matlab.ui.control.ListBox
        GoButton                  matlab.ui.control.Button
        GoRelativeButton          matlab.ui.control.Button
        GoAbsoluteButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        SettingApp % Dialog box for setting app
        gantry;
        cam;
        joy;
        fid;
        focus;
        %         petal
        %         gluing;
        %         dispenser;
    end
    
    properties (Access = public)
        petal;
        gluing;
        dispenser; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gantry = STAGES(2);
            %            app.joy = JOYSTICK (app.gantry);
            app.fid=FIDUCIALS(1);
            app.cam= CAMERA(5);
            app.SelectListBox.Enable = 0;
            
            fiducial_1 = [133.3216, -359.4739];
            fiducial_2 = [277.0741, 223.8666];
            app.petal = PETALCS(0, fiducial_1, fiducial_2);

        end

        % Value changed function: XSwitch
        function XSwitchValueChanged(app, event)
            value = app.XSwitch.Value;
            switch value
                case 'On'
                    app.XSwitchLabel.BackgroundColor = 'g';
                    app.gantry.MotorEnable(app.gantry.X)
                case 'Off'
                    app.XSwitchLabel.BackgroundColor = 'r';
                    app.gantry.MotorDisable(app.gantry.X)
            end
        end

        % Value changed function: YSwitch
        function YSwitchValueChanged(app, event)
            value = app.YSwitch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnable(app.gantry.Y)
                case 'Off'
                    app.gantry.MotorDisable(app.gantry.Y)
            end
        end

        % Value changed function: Z1Switch
        function Z1SwitchValueChanged(app, event)
            value = app.Z1Switch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnable(app.gantry.Z1)
                case 'Off'
                    app.gantry.MotorDisable(app.gantry.Z1)
            end
        end

        % Value changed function: Z2Switch
        function Z2SwitchValueChanged(app, event)
            value = app.Z2Switch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnable(app.gantry.Z2)
                case 'Off'
                    app.gantry.MotorDisable(app.gantry.Z2)
            end
        end

        % Value changed function: AllSwitch
        function AllSwitchValueChanged(app, event)
            value = app.AllSwitch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnableAll()
                case 'Off'
                    app.gantry.MotorDisableAll()
            end
            %             app.XSwitch.Value = value;
            %             app.YSwitch.Value = value;
            %             app.Z1Switch.Value = value;
            %             app.Z2Switch.Value = value;
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            if value == 1
                app.StopButton.BackgroundColor='r';
                app.gantry.MotionAbortAll();
            else
                app.StopButton.BackgroundColor = 'g';
            end
        end

        % Callback function
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            switch value
                case 'On'
                    app.ConnectGantryCheckBox.Value = 1;
                    app.ConnectJoystickCheckBox.Value = 1;
                    app.ConnectCameraCheckBox.Value = 1;
                    app.ConnectDispenserCheckBox.Value = 1;
                case 'Off'
                    app.ConnectGantryCheckBox.Value = 0;
                    app.ConnectJoystickCheckBox.Value = 0;
                    app.ConnectCameraCheckBox.Value = 0;
                    app.ConnectDispenserCheckBox.Value = 0;
            end
            %             app.ConnectGantryCheckBox.ValueChanged();
            %             app.ConnectJoystickCheckBox.ValueChanged();
            %             app.ConnectCameraCheckBox.ValueChanged();
            %             app.ConnectDispenserCheckBox.ValueChanged();
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

            if isprop(app.dispenser,'IsConnected')
                if app.dispenser.IsConnected == 1
                    app.ConnectDispenserCheckBox.FontColor = 'g';
                else
                    app.ConnectDispenserCheckBox.FontColor = 'k';
                end
            end
            
            if isprop(app.cam,'IsConnected') && app.cam.IsConnected == 1
                app.ConnectCameraCheckBox.FontColor = 'g';
            else
                app.ConnectCameraCheckBox.FontColor = 'k';
            end
            
            % Updating indicators
            if isprop(app.gantry,'IsConnected') && app.gantry.IsConnected == 1
                app.ConnectGantryCheckBox.FontColor = 'g';
                app.XSwitch.Enable = 1;
                app.YSwitch.Enable = 1;
                app.Z1Switch.Enable = 1;
                app.Z2Switch.Enable = 1;
                app.USwitch.Enable = 1;
                app.AllSwitch.Enable = 1;
                disp("Motor State de la App")
                disp(app.gantry.MotorState(app.gantry.X));
                if app.gantry.MotorState(app.gantry.X) == ACS.SPiiPlusNET.MotorStates.ACSC_MST_ENABLE
                    
                    app.Lamp_xMotor.Color = 'g';
                    app.XSwitch.Value = 'On';
                else
                    app.Lamp_xMotor.Color = 'r';
                    app.XSwitch.Value = 'Off';
                    
                end
                
%                 if app.gantry.MotorState(app.gantry.Y) == 4194321
%                     app.Lamp_yMotor.Color = 'g';
%                     app.YSwitch.Value = 'On';
%                 else
%                     app.Lamp_yMotor.Color = 'r';
%                     app.YSwitch.Value = 'Off';
%                 end
%                 
%                 if app.gantry.MotorState(app.gantry.Z1) == 4194321
%                     app.Lamp_z1Motor.Color = 'g';
%                     app.Z1Switch.Value = 'On';
%                 else
%                     app.Lamp_z1Motor.Color = 'r';
%                     app.Z1Switch.Value = 'Off';
%                 end
%                 
%                 if app.gantry.MotorState(app.gantry.Z2) == 4194321
%                     app.Lamp_z2Motor.Color = 'g';
%                     app.Z2Switch.Value = 'On';
%                 else
%                     app.Lamp_z2Motor.Color = 'r';
%                     app.Z2Switch.Value = 'On';
%                 end
%                 if app.gantry.MotorState(app.gantry.U) == 4194321
%                     app.Lamp_uMotor.Color = 'g';
%                     app.Z2Switch.Value = 'On';
%                 else
%                     app.Lamp_uMotor.Color = 'r';
%                     app.Z2Switch.Value = 'On';
%                 end
                
                
                app.XEditField.Value = app.gantry.GetPosition(app.gantry.X);
                app.YEditField.Value = app.gantry.GetPosition(app.gantry.Y);
                app.Z1EditField.Value = app.gantry.GetPosition(app.gantry.Z1);
                app.Z2EditField.Value = app.gantry.GetPosition(app.gantry.Z2);
                app.EditField.Value = app.gantry.GetPosition(app.gantry.U);
                
            else
                app.ConnectGantryCheckBox.FontColor = 'k';
                app.Lamp_xMotor.Color = [1,1,1];
                app.Lamp_yMotor.Color = [1,1,1];
                app.Lamp_z1Motor.Color = [1,1,1];
                app.Lamp_z2Motor.Color = [1,1,1];
                app.Lamp_uMotor.Color = [1,1,1];
                app.XSwitch.Enable = 0;
                app.YSwitch.Enable = 0;
                app.Z1Switch.Enable = 0;
                app.Z2Switch.Enable = 0;
                app.USwitch.Enable = 0;
                app.AllSwitch.Enable = 0;
            end
            
            
            drawnow limitrate;
            plot(app.UIAxes,[0.1 0.2 0.3 0.4],'-r');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,[1.0 0.9 0.4 0.7],'--b');
        end

        % Value changed function: USwitch
        function USwitchValueChanged(app, event)
            value = app.USwitch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnable(app.gantry.U)
                case 'Off'
                    app.gantry.MotorDisable(app.gantry.U)
            end
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            if app.ConnectGantryCheckBox.Value == 1
                fprintf("\nGantry connection : %d \n", app.gantry.IsConnected);
                if app.gantry.IsConnected ~= 1
                    app.gantry.Connect;
                    app.joy = JOYSTICK (app.gantry);
                else
                    fprintf("Gantry is already connected");
                end
                fprintf("\nFinally Gantry connection : %d \n", app.gantry.IsConnected);
            end
            if app.ConnectCameraCheckBox.Value == 1
                app.cam.Connect;
            end
            if app.ConnectDispenserCheckBox.Value == 1
                app.dispenser = DISPENSER;
                app.dispenser.Connect;
            end
        end

        % Button pushed function: DisconnectButton_2
        function DisconnectButton_2Pushed(app, event)
            if app.ConnectGantryCheckBox.Value == 1 && isprop(app.gantry,'IsConnected')
                app.gantry.Disconnect;
            end
            if app.ConnectCameraCheckBox.Value == 1 && isprop(app.cam,'IsConnected')
                app.cam.Disconnect;
            end
            if app.ConnectDispenserCheckBox.Value == 1 && isprop(app.dispenser,'IsConnected')
                app.dispenser.Disconnect;
            end
        end

        % Selection changed function: ActionsButtonGroup
        function ActionsButtonGroupSelectionChanged(app, event)
            disp (app.ActionsButtonGroup.SelectedObject)
            selectedButton = app.ActionsButtonGroup.SelectedObject.Text;
            disp(selectedButton);
            fprintf("\n%s is pressed\n", selectedButton);
            app.SelectListBox.Enable = 0;
            switch selectedButton
                case 'Manual'
                    app.SelectListBox.Enable = false;
                case 'Home'
                    app.SelectListBox.Enable = false;
                    app.gantry.HomeAll;
                    %                                 app.ActionsButtonGroup.Layout.
                    %                                 set(ActionsButtonGroup 'String', 'STOP');
                    app.ActionsButtonGroup.SelectedObject.Enable = false;
                    %                                 app.ActionsButtonGroup.SelectedObject.Text = 'Manual';
                    %                                 set(app.ActionsButtonGroup.SelectedObject.Text, 'Manual');
                    %                                 app.ActionsButtonGroup.BusyAction = 'queue';
                    %                                 app.ActionsButtonGroup.SelectedObject
                    %                     fprintf("\n%s is pressed\n", selectedButton);
                case 'Locate Petal'
                    app.SelectListBox.Enable = false;
                    %                                 app.ActionsButtonGroup.SelectedObject.Enable = false;
                    %                                 app.ActionsButtonGroupSelectionChanged('Manual')
                    %                     fprintf("\n%s is pressed\n", selectedButton);
                case 'zz'
                    app.ActionsButtonGroup.SelectedObject.Text = 'Manual';
                    app.SelectListBox.Enable = false;
                    %                     fprintf("\n%s is pressed\n", selectedButton);
                case 'Glue dispensing'
                    app.SelectListBox.Enable = true;
                    
                    %                     fprintf("\n%s is pressed\n", selectedButton);
            end
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            app.SettingsButton.Enable = 'Off';
            app.SettingApp = Settings(app,app.dispenser);
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.SettingApp)
            delete(app)
        end

        % Selection changed function: MovementButtonGroup
        function MovementButtonGroupSelectionChanged(app, event)
            selectedButton = app.MovementButtonGroup.SelectedObject;
            if app.JoystickButton == 1
                if  isprop(app.joy,'IsConnected') && app.joy.IsConnected ~= 1
                    app.joy.Connect;
                    app.XEditField_4.Editable = 0;
                    app.YEditField_4.Editable = 0;
                    app.Z1EditField_4.Editable = 0;
                    app.Z2EditField_4.Editable = 0;
                    app.UEditField_4.Editable = 0;
                else
                    app.joy.Disconnect;
                end
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.gluing = PetalDispensing(app.dispenser,app.gantry,app.petal);
            sensor = app.SelectListBox.Value();
            app.gluing.DispenseTime(sensor);
        end

        % Button pushed function: GoRelativeButton
        function GoRelativeButtonPushed(app, event)
            app.gantry.MoveBy(app.gantry.X, app.XEditField_3,5,0);
%             app.gantry.MoveBy(app.gantry.Y,app.YEditField_3,5,0);
%             app.gantry.MoveBy(app.gantry.Z1,app.X1.EditField_3,5,0);
%             app.gantry.MoveBy(app.gantry.Z2,app.XZ2.EditField_3,5,0);
%            app.gantry.MoveBy(app.gantry.U,app.EditField_3,app.EditField_4,0); 
        end

        % Button pushed function: GoAbsoluteButton
        function GoAbsoluteButtonPushed(app, event)
        disp(app.xEditField_2)
            app.gantry.MoveTo(app.gantry.X, app.XEditField_2, 5, 0);
%             app.gantry.MoveTo(app.gantry.Y,app.YEditField_2,5,0);
%             app.gantry.MoveTo(app.gantry.Z1,app.XEditField_2,5,0);
%             app.gantry.MoveTo(app.gantry.Z2,app.XEditField_2,5,0);
%            app.gantry.MoveTo(app.gantry.U,app.EditField_3,app.EditField_4,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gantry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [-10 100];
            app.ActionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ActionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ActionsButtonGroupSelectionChanged, true);
            app.ActionsButtonGroup.Title = 'Actions';
            app.TouchDownButton = uitogglebutton(app.ActionsButtonGroup);
            app.TouchDownButton.Text = 'TouchDown';
            app.ManualButton = uitogglebutton(app.ActionsButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.zzButton = uitogglebutton(app.ActionsButtonGroup);
            app.zzButton.Text = 'zz';
            app.HomeButton = uitogglebutton(app.ActionsButtonGroup);
            app.HomeButton.Text = 'Home';
            app.GluedispensingButton = uitogglebutton(app.ActionsButtonGroup);
            app.GluedispensingButton.Text = 'Glue dispensing';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.YEditField.Editable = 'off';
            app.Z1EditFieldLabel = uilabel(app.UIFigure);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField.ValueDisplayFormat = '%.3f';
            app.Z1EditField.Editable = 'off';
            app.Z2EditFieldLabel = uilabel(app.UIFigure);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField.ValueDisplayFormat = '%.3f';
            app.Z2EditField.Editable = 'off';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Ø';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.ConnectGantryCheckBox = uicheckbox(app.UIFigure);
            app.ConnectGantryCheckBox.Text = 'Connect Gantry';
            app.ConnectDispenserCheckBox = uicheckbox(app.UIFigure);
            app.ConnectDispenserCheckBox.Text = 'Connect Dispenser';
            app.ConnectCameraCheckBox = uicheckbox(app.UIFigure);
            app.ConnectCameraCheckBox.Text = 'Connect Camera';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.Limits = [-500 500];
            app.XEditField_2.ValueDisplayFormat = '%.3f';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.Limits = [-500 500];
            app.YEditField_2.ValueDisplayFormat = '%.3f';
            app.Z1EditField_2Label = uilabel(app.UIFigure);
            app.Z1EditField_2Label.Text = 'Z1';
            app.Z1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField_2.Limits = [-10 100];
            app.Z1EditField_2.ValueDisplayFormat = '%.3f';
            app.Z2EditField_2Label = uilabel(app.UIFigure);
            app.Z2EditField_2Label.Text = 'Z2';
            app.Z2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField_2.Limits = [-10 100];
            app.Z2EditField_2.ValueDisplayFormat = '%.3f';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Ø';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CurrentPositionLabel = uilabel(app.UIFigure);
            app.CurrentPositionLabel.Text = 'Current Position';
            app.GantryMotorsPanel = uipanel(app.UIFigure);
            app.GantryMotorsPanel.Title = 'Gantry Motors';
            app.UIAxes2 = uiaxes(app.GantryMotorsPanel);
            title(app.UIAxes2, 'Motor Current')
            xlabel(app.UIAxes2, {''; ''})
            ylabel(app.UIAxes2, 'mA')
            app.Lamp_xMotor = uilamp(app.GantryMotorsPanel);
            app.Lamp_yMotor = uilamp(app.GantryMotorsPanel);
            app.Lamp_z1Motor = uilamp(app.GantryMotorsPanel);
            app.Lamp_z2Motor = uilamp(app.GantryMotorsPanel);
            app.Lamp_uMotor = uilamp(app.GantryMotorsPanel);
            app.XSwitchLabel = uilabel(app.GantryMotorsPanel);
            app.XSwitchLabel.Text = 'X';
            app.XSwitch = uiswitch(app.GantryMotorsPanel, 'slider');
            app.XSwitch.ValueChangedFcn = createCallbackFcn(app, @XSwitchValueChanged, true);
            app.YSwitchLabel = uilabel(app.GantryMotorsPanel);
            app.YSwitchLabel.Text = 'Y';
            app.YSwitch = uiswitch(app.GantryMotorsPanel, 'slider');
            app.YSwitch.ValueChangedFcn = createCallbackFcn(app, @YSwitchValueChanged, true);
            app.Z1SwitchLabel = uilabel(app.GantryMotorsPanel);
            app.Z1SwitchLabel.Text = 'Z1';
            app.Z1Switch = uiswitch(app.GantryMotorsPanel, 'slider');
            app.Z1Switch.ValueChangedFcn = createCallbackFcn(app, @Z1SwitchValueChanged, true);
            app.Z2SwitchLabel = uilabel(app.GantryMotorsPanel);
            app.Z2SwitchLabel.Text = 'Z2';
            app.Z2Switch = uiswitch(app.GantryMotorsPanel, 'slider');
            app.Z2Switch.ValueChangedFcn = createCallbackFcn(app, @Z2SwitchValueChanged, true);
            app.AllSwitchLabel = uilabel(app.GantryMotorsPanel);
            app.AllSwitchLabel.Text = 'All';
            app.AllSwitch = uiswitch(app.GantryMotorsPanel, 'toggle');
            app.AllSwitch.ValueChangedFcn = createCallbackFcn(app, @AllSwitchValueChanged, true);
            app.USwitchLabel = uilabel(app.GantryMotorsPanel);
            app.USwitchLabel.Text = 'U';
            app.USwitch = uiswitch(app.GantryMotorsPanel, 'slider');
            app.USwitch.ValueChangedFcn = createCallbackFcn(app, @USwitchValueChanged, true);
            app.StopButton = uibutton(app.GantryMotorsPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.ConnectButton_2 = uibutton(app.UIFigure, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.DisconnectButton_2 = uibutton(app.UIFigure, 'push');
            app.DisconnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_2Pushed, true);
            app.DisconnectButton_2.Text = 'Disconnect';
            app.XEditField_3Label = uilabel(app.UIFigure);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_3.Limits = [-500 500];
            app.XEditField_3.ValueDisplayFormat = '%.3f';
            app.YEditField_3Label = uilabel(app.UIFigure);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_3.Limits = [-500 500];
            app.YEditField_3.ValueDisplayFormat = '%.3f';
            app.Z1EditField_3Label = uilabel(app.UIFigure);
            app.Z1EditField_3Label.Text = 'Z1';
            app.Z1EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField_3.Limits = [-10 100];
            app.Z1EditField_3.ValueDisplayFormat = '%.3f';
            app.Z2EditField_3Label = uilabel(app.UIFigure);
            app.Z2EditField_3Label.Text = 'Z2';
            app.Z2EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField_3.Limits = [-10 100];
            app.Z2EditField_3.ValueDisplayFormat = '%.3f';
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Ø';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.CameraViewPanel = uipanel(app.UIFigure);
            app.CameraViewPanel.Title = 'Camera View';
            app.SettingsButton = uibutton(app.UIFigure, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.MovementButtonGroup = uibuttongroup(app.UIFigure);
            app.MovementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MovementButtonGroupSelectionChanged, true);
            app.MovementButtonGroup.Title = 'Movement';
            app.JoystickButton = uiradiobutton(app.MovementButtonGroup);
            app.JoystickButton.Text = 'Joystick';
            app.DefaultButton = uiradiobutton(app.MovementButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.LinearButton = uiradiobutton(app.MovementButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.AutoButton = uiradiobutton(app.MovementButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.EditFieldLabel_2 = uilabel(app.UIFigure);
            app.EditFieldLabel_2.Text = 'Ø';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Limits = [-30 30];
            app.EditField_4.Value = 5;
            app.SpeedLabel = uilabel(app.UIFigure);
            app.SpeedLabel.Text = 'Speed';
            app.XEditField_4Label = uilabel(app.UIFigure);
            app.XEditField_4Label.Text = 'X';
            app.XEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_4.Limits = [-30 30];
            app.XEditField_4.ValueDisplayFormat = '%d';
            app.XEditField_4.Value = 5;
            app.YEditField_4Label = uilabel(app.UIFigure);
            app.YEditField_4Label.Text = 'Y';
            app.YEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_4.Limits = [-30 30];
            app.YEditField_4.ValueDisplayFormat = '%d';
            app.YEditField_4.Value = 5;
            app.Z1EditField_4Label = uilabel(app.UIFigure);
            app.Z1EditField_4Label.Text = 'Z1';
            app.Z1EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField_4.Limits = [-30 30];
            app.Z1EditField_4.ValueDisplayFormat = '%d';
            app.Z1EditField_4.Value = 5;
            app.Z2EditField_4Label = uilabel(app.UIFigure);
            app.Z2EditField_4Label.Text = 'Z2';
            app.Z2EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField_4.Limits = [-30 30];
            app.Z2EditField_4.ValueDisplayFormat = '%d';
            app.Z2EditField_4.Value = 5;
            app.SelectListBoxLabel = uilabel(app.UIFigure);
            app.SelectListBoxLabel.Text = 'Select';
            app.SelectListBox = uilistbox(app.UIFigure);
            app.SelectListBox.Items = {'R0', 'R1', 'R2', 'R3S0', 'R3S1', 'R4S0', 'R4S1', 'R5S0', 'R5S1'};
            app.SelectListBox.Value = 'R0';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.GoRelativeButton = uibutton(app.UIFigure, 'push');
            app.GoRelativeButton.ButtonPushedFcn = createCallbackFcn(app, @GoRelativeButtonPushed, true);
            app.GoRelativeButton.Text = 'Go Relative';
            app.GoAbsoluteButton = uibutton(app.UIFigure, 'push');
            app.GoAbsoluteButton.ButtonPushedFcn = createCallbackFcn(app, @GoAbsoluteButtonPushed, true);
            app.GoAbsoluteButton.Text = 'Go Absolute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end